classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        SintetizadorTab           matlab.ui.container.Tab
        brn_1                     matlab.ui.control.Button
        btn_2                     matlab.ui.control.Button
        btn_3                     matlab.ui.control.Button
        btn_4                     matlab.ui.control.Button
        btn_5                     matlab.ui.control.Button
        btn_6                     matlab.ui.control.Button
        btn_7                     matlab.ui.control.Button
        btn_8                     matlab.ui.control.Button
        btn_9                     matlab.ui.control.Button
        btn_ast                   matlab.ui.control.Button
        btn_0                     matlab.ui.control.Button
        btn_num                   matlab.ui.control.Button
        displaySint               matlab.ui.container.Panel
        displayTextSint           matlab.ui.control.Label
        f1f2Label                 matlab.ui.control.Label
        Hz1336Hz1477HzLabel       matlab.ui.control.Label
        HzLabel                   matlab.ui.control.Label
        HzLabel_2                 matlab.ui.control.Label
        HzLabel_3                 matlab.ui.control.Label
        HzLabel_4                 matlab.ui.control.Label
        btn_clear                 matlab.ui.control.Button
        btn_sintRun               matlab.ui.control.Button
        saveCheckBox              matlab.ui.control.CheckBox
        runLastSignalSint         matlab.ui.control.Button
        DecodificadorTab          matlab.ui.container.Tab
        btn_decRun                matlab.ui.control.Button
        displayDec                matlab.ui.container.Panel
        displayTextDec            matlab.ui.control.Label
        btn_runLastSignalDec      matlab.ui.control.Button
        btn_showStartTimeDec      matlab.ui.control.Button
        btn_runFilteredSignalDec  matlab.ui.control.Button
        panelDeGraficos           matlab.ui.container.Panel
        Plane                     matlab.ui.control.UIAxes
        GraficoLabel              matlab.ui.control.Label
        PlotSelectDropDownSint    matlab.ui.control.DropDown
        DigitoDropDownLabel       matlab.ui.control.Label
        DigitDropDown             matlab.ui.control.DropDown
        consoleText               matlab.ui.control.Label
    end


    properties (Access = public)
        
        Fs = 40000
        
        % Sintetizador...!
        Y_sint = []
        Y_sint_mag = []
        Y_sint_phase = []
        t_signal_sint = linspace(0, 3.75, 150015)
        f_signal_sint = linspace(-40000/2, 40000/2, 150015)
        N_digits = 0
        TD = 0.25
        delayArray = zeros(1, 10001)
        
        % Decodificador...!
        Y_dec = []
        Y_dec_mag = []
        Y_dec_phase = []
        Y_filtered = []
        graphDecBoxesArray = []
        t_recording = 3.75
        t_signal_dec = linspace(0, 3.75, 150000)
        t_tone_dec = linspace(0, 0.25, 10001)
        f_signal_dec = linspace(-40000/2, 40000/2, 150000)
        f_signal_dec_filt = linspace(0, 40000, 150000)
        f_tone_dec = linspace(-40000/2, 40000/2, 10001)
        Ns_tone = 10000
        recObj = audiorecorder(40000, 16, 1)
        array_tones_dec = zeros(7, 10001)
        array_tones_dec_mag = zeros(7, 10001)
        array_tones_dec_phase = zeros(7, 10001)
        begin = 0
        omegaLess = 650
        omegaHigher = 1550
    end

    methods (Access = public)
    
        function digitButtonPushed(app, digit)
            app.btn_clear.Enable = 'on';
            if app.N_digits < 7
                app.displayTextSint.Text = strcat(app.displayTextSint.Text, digit);
                app.N_digits = app.N_digits + 1;
                [f1, f2] = getFrequencies(digit);
                [y, time] = DTMF(f1, f2, app.Fs, app.TD);
                soundsc(y, app.Fs);
            else
                app.consoleText.Text = '>> Solamente se puede generar 7 tonos en cada archivo...!';
                app.consoleText.FontColor = [1.00, 0.00, 0.00];
            end
        end
        
        function plotFunction(app, input, xData, yData)
            
            tab = app.TabGroup.SelectedTab.Title;
            
            app.Plane.YTickLabelMode = 'auto';
            app.Plane.XTickLabelMode = 'auto';
            app.Plane.YTickMode = 'auto';
            app.Plane.XTickMode = 'auto';
            app.Plane.YLimMode = 'auto';
            app.Plane.XLimMode = 'auto';
            app.Plane.DataAspectRatioMode = 'auto';
            
            switch input
                case 'signal: amplitude vs time'
                    if app.TabGroup.SelectedTab == app.SintetizadorTab
                        app.Plane.YLim = [-2.5, 2.5];
                    end
                    app.Plane.XLim = [0, 4];
                case 'signal: magnitude vs frequency'
                    if app.TabGroup.SelectedTab == app.SintetizadorTab
                        app.Plane.YLim = [0, 35000];
                    end
                    app.Plane.XLim = [600, 1600];
                    app.Plane.XTick = (600:100:1600);
                case 'signal: phase vs frequency'
                    app.Plane.XLim = [600, 1600];
                    app.Plane.XTick = (600:100:1600);
                case 'signal: filtered vs time'
                    app.Plane.XLim = [0, 4];
                case 'tones: amplitude vs time'
                    app.Plane.XLim = [0, 0.25];
                case 'tones: magnitude vs frequency'
                    app.Plane.XLim = [600, 1600];
                    app.Plane.XTick = (600:100:1600);
                case 'tones: phase vs frequency'
                    app.Plane.XLim = [600, 1600];
                    app.Plane.XTick = (600:100:1600);
            end
            
            plot(app.Plane, xData, yData);
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Plane.GridAlpha = 0.1;
            app.PlotSelectDropDownSint.Enable = 'off';
            app.btn_runFilteredSignalDec.Enable = 'off';
            app.btn_runLastSignalDec.Enable = 'off';
            app.btn_showStartTimeDec.Enable = 'off';
            app.consoleText.Enable = 'on';
            app.DigitDropDown.BackgroundColor = [1 1 1];
            app.DigitDropDown.Enable = 'off';
            app.displayTextDec.Text = '>>'; 
        end

        % Callback function
        function AllPlotsCheckBoxValueChanged(app, event)
            value = app.AllPlotsCheckBox.Value;
            if value
                for i = 1:1:6
                    app.graphDecBoxesArray(i).Value = 1;
                end
            else
                for i = 1:1:6
                    app.graphDecBoxesArray(i).Value = 0;
                end
            end
        end

        % Callback function
        function ColorSelectDropDownValueChanged(app, event)
            
        end

        % Value changed function: DigitDropDown
        function DigitDropDownValueChanged(app, event)
            value = app.DigitDropDown.Value;
            arrayDigitsValue = {'1°' '2°' '3°' '4°' '5°' '6°' '7°'};
            iPlot = 0;
            
            for i = 1:1:7
                arrayDigitsValue(i)
                if value == cell2mat(arrayDigitsValue(i))
                    iPlot = i;
                end
            end
            
            iPlot;
            
            switch app.PlotSelectDropDownSint.Value
                case 'Amplitud de tonos vs Tiempo (s)'
                    plotFunction(app, 'tones: amplitude vs time', app.t_tone_dec, app.array_tones_dec(iPlot, :));
                case 'Magnitud de tonos (dB) vs Frecuencia (Hz)'
                    plotFunction(app, 'tones: magnitude vs frequency', app.f_tone_dec, app.array_tones_dec_mag(iPlot, :));
                case 'Fase de tonos (rad) vs Frecuencia (Hz)'
                    plotFunction(app, 'tones: phase vs frequency', app.f_tone_dec, app.array_tones_dec_phase(iPlot, :));
            end
        end

        % Callback function
        function GridCheckBoxValueChanged(app, event)
            
        end

        % Value changed function: PlotSelectDropDownSint
        function PlotSelectDropDownSintValueChanged(app, event)
            value = app.PlotSelectDropDownSint.Value;
            tab = app.TabGroup.SelectedTab;
            switch value
                case 'Amplitud de señal completa vs Tiempo (s)'
                    if tab == app.SintetizadorTab
                        plotFunction(app, 'signal: amplitude vs time', app.t_signal_sint, app.Y_sint);
                    elseif tab == app.DecodificadorTab
                        app.DigitDropDown.Value = '1°';
                        app.DigitDropDown.Enable = 'off';
                        plotFunction(app, 'signal: amplitude vs time', app.t_signal_dec, app.Y_dec);
                    end
                case 'Magnitud de señal completa (dB)  vs Frecuencia (Hz)'
                    if tab == app.SintetizadorTab
                        plotFunction(app, 'signal: magnitude vs frequency', app.f_signal_sint, app.Y_sint_mag);
                    elseif tab == app.DecodificadorTab
                        app.DigitDropDown.Value = '1°';
                        app.DigitDropDown.Enable = 'off';
                        plotFunction(app, 'signal: magnitude vs frequency', app.f_signal_dec, app.Y_dec_mag);
                    end
                case 'Fase de señal completa (rad) vs Frecuencia (Hz)'
                    if tab == app.SintetizadorTab
                        plotFunction(app, 'signal: phase vs frequency', app.f_signal_sint, app.Y_sint_phase);
                    elseif tab == app.DecodificadorTab
                        app.DigitDropDown.Value = '1°';
                        app.DigitDropDown.Enable = 'off';
                        plotFunction(app, 'signal: phase vs frequency', app.f_signal_dec, app.Y_dec_phase);
                    end
                case 'Amplitud de señal completa filtrada vs Tiempo(s)'
                    app.DigitDropDown.Value = '1°';
                    app.DigitDropDown.Enable = 'off';
                    plotFunction(app, 'signal: filtered vs time', app.t_signal_dec, app.Y_filtered);
                case 'Amplitud de tonos vs Tiempo (s)'
                    app.DigitDropDown.Enable = 'on';
                    app.DigitDropDown.FontColor = [0 0 0];
                    app.DigitDropDown.Value = '1°';
                    plotFunction(app, 'tones: amplitude vs time', app.t_tone_dec, app.array_tones_dec(1, :));
                case 'Magnitud de tonos (dB) vs Frecuencia (Hz)'
                    app.DigitDropDown.Enable = 'on';
                    app.DigitDropDown.FontColor = [0 0 0];
                    app.DigitDropDown.Value = '1°';
                    plotFunction(app, 'tones: magnitude vs frequency', app.f_tone_dec, app.array_tones_dec_mag(1, :));
                case 'Fase de tonos (rad) vs Frecuencia (Hz)'
                    app.DigitDropDown.Enable = 'on';
                    app.DigitDropDown.FontColor = [0 0 0];
                    app.DigitDropDown.Value = '1°';
                    plotFunction(app, 'tones: phase vs frequency', app.f_tone_dec, app.array_tones_dec_phase(1, :));
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            tab = app.TabGroup.SelectedTab;
            if tab == app.SintetizadorTab
                app.DigitDropDown.Visible = 'off';
            elseif tab == app.DecodificadorTab
                app.DigitDropDown.Enable = 'off';
                app.DigitDropDown.Visible = 'on';
            end
        end

        % Button pushed function: brn_1
        function brn_1_Pushed(app, event)
            app.digitButtonPushed('1');
        end

        % Button pushed function: btn_0
        function btn_0ButtonPushed(app, event)
            app.digitButtonPushed('0');
        end

        % Button pushed function: btn_2
        function btn_2ButtonPushed(app, event)
            app.digitButtonPushed('2');
        end

        % Button pushed function: btn_3
        function btn_3ButtonPushed(app, event)
            app.digitButtonPushed('3');
        end

        % Button pushed function: btn_4
        function btn_4ButtonPushed(app, event)
            app.digitButtonPushed('4');
        end

        % Button pushed function: btn_5
        function btn_5ButtonPushed(app, event)
            app.digitButtonPushed('5');
        end

        % Button pushed function: btn_6
        function btn_6ButtonPushed(app, event)
            app.digitButtonPushed('6');
        end

        % Button pushed function: btn_7
        function btn_7ButtonPushed(app, event)
            app.digitButtonPushed('7');
        end

        % Button pushed function: btn_8
        function btn_8ButtonPushed(app, event)
            app.digitButtonPushed('8');
        end

        % Button pushed function: btn_9
        function btn_9ButtonPushed(app, event)
            app.digitButtonPushed('9');
        end

        % Button pushed function: btn_ast
        function btn_astButtonPushed(app, event)
            app.digitButtonPushed('*');
        end

        % Button pushed function: btn_clear
        function btn_clearButtonPushed(app, event)
            app.N_digits = 0;
            app.displayTextSint.Text = '';
            app.btn_clear.Enable = 'off';
            app.consoleText.FontColor = [0.00, 0.00, 0.00];
            app.consoleText.Text = '>>';
        end

        % Button pushed function: btn_decRun
        function btn_decRunButtonPushed(app, event)
            graphLabelsArray = {
                'Amplitud de tonos vs Tiempo (s)'
                'Magnitud de tonos (dB) vs Frecuencia (Hz)'
                'Fase de tonos (rad) vs Frecuencia (Hz)'
                'Amplitud de señal completa vs Tiempo (s)'
                'Magnitud de señal completa (dB)  vs Frecuencia (Hz)'
                'Fase de señal completa (rad) vs Frecuencia (Hz)'
                'Amplitud de señal completa filtrada vs Tiempo(s)'
            };
            
            app.PlotSelectDropDownSint.Enable = 'on';
            app.PlotSelectDropDownSint.FontColor = [0 0 0];
            app.PlotSelectDropDownSint.Items = graphLabelsArray;
            
            % Recording signal...!
            app.consoleText.FontColor = [0 0 0];
            app.consoleText.Text = '>> Por favor empiece la reproducción en';
            pause(0.5);
            app.consoleText.Text = [app.consoleText.Text, ' ... '];
            pause(0.5);
            app.consoleText.Text = [app.consoleText.Text, '3'];
            pause(0.5);
            app.consoleText.Text = [app.consoleText.Text, ' ... '];
            pause(0.5);
            app.consoleText.Text = [app.consoleText.Text, '2'];
            pause(0.5);
            app.consoleText.Text = [app.consoleText.Text, ' ... '];
            pause(0.5);
            app.consoleText.Text = [app.consoleText.Text, '1'];
            pause(0.5);
            app.consoleText.Text = [app.consoleText.Text, ' ... '];
            pause(0.5);
            app.consoleText.Text = [app.consoleText.Text, '¡Ahora!'];
            
            recordblocking(app.recObj, app.t_recording);
            
            app.consoleText.Text = '>> Ya puede finalizar la reproducción...!';
            
            y = getaudiodata(app.recObj);
            app.Y_dec = y';
            
            app.btn_runLastSignalDec.Enable = 'on';
            app.btn_runFilteredSignalDec.Enable = 'on';
            app.btn_showStartTimeDec.Enable = 'on'; 
            
            gap = 10000;
            step = 0;
            
            for i = 1:1:7
                if i == 1
                    app.array_tones_dec(1, :) = app.Y_dec(1 , 25000 : 25000 + app.Ns_tone);
                    step = (2 * gap) + app.Ns_tone;
                else
                    app.array_tones_dec(i, :) = app.Y_dec(1, step + gap : step + (2 * gap));
                    step = step + gap + app.Ns_tone;
                end
            end
            
            % FFT to full signal...!
            Y_fft = fft(app.Y_dec, length(app.Y_dec));
            app.Y_dec_mag = abs(fftshift(Y_fft));
            app.Y_dec_phase = unwrap(angle(Y_fft));
            
            % Filtering and rebuild full signal...!
            Y_mag_filtered = BandPassFilter(fftshift(abs(Y_fft)), app.f_signal_dec, app.omegaLess, app.omegaHigher);
            Y_rebuild = fftshift(Y_mag_filtered) .* exp(1i * angle(Y_fft));
            app.Y_filtered = ifft(Y_rebuild, length(Y_rebuild), 'symmetric');
            
            %             subplot(2, 1, 1);
            %             plot(app.f_signal_dec, fftshift(abs(Y_fft)), 'b');
            %             xlim([0, 0.5e+04]);
            %             grid on;
            %             
            %             subplot(2, 1, 2);
            %             plot(app.f_signal_dec, Y_mag_filtered, 'r');
            %             xlim([0, 0.5e+04]);
            %             grid on;
            
            % FFT to single tone...!
            for i = 1:1:7
                array_tones_dec_fft = fft(app.array_tones_dec(i, :));
                array_tones_dec_fft_center = fftshift(array_tones_dec_fft);
                app.array_tones_dec_mag(i, :) = abs(array_tones_dec_fft_center);
                app.array_tones_dec_phase(i, :) = unwrap(angle(array_tones_dec_fft_center));
            end
            
            % Filtering single tones...!
            for i = 1:1:7
                app.array_tones_dec_mag(i, :) = BandPassFilter(app.array_tones_dec_mag(i, :), app.f_tone_dec, app.omegaLess, app.omegaHigher);
            end
            
            % Decoding tones...!
            decoded_Freq = zeros(7, 2);
            f_tone_dec_abs = abs(app.f_tone_dec);
            array_tones_buffer = app.array_tones_dec_mag;
            
            for i = 1:1:7
                [peaks, locs] = findpeaks(array_tones_buffer(i, :));
                [M, I] = max(peaks);
                array_tones_buffer(i, locs(I)) = 0;
                decoded_Freq(i, 1) = f_tone_dec_abs(locs(I));
                first = f_tone_dec_abs(locs(I));
                
                [peaks, locs] = findpeaks(array_tones_buffer(i, :));
                [M, index] = max(peaks);
                array_tones_buffer(i, locs(I)) = 0;
                buffer = f_tone_dec_abs(locs(I));
                
                while buffer < first + 50 & buffer > first - 50
                    [peaks, locs] = findpeaks(array_tones_buffer(i, :));
                    [M, I] = max(peaks);
                    array_tones_buffer(i, locs(I)) = 0;
                    buffer = f_tone_dec_abs(locs(I));
                end
                
                decoded_Freq(i, 2) = buffer;
            end
            
            output = char.empty(0, 7);
            
            for i = 1:1:7
                if decoded_Freq(i, 1) > decoded_Freq(i, 2)
                    f1 = decoded_Freq(i, 2);
                    f2 = decoded_Freq(i, 1);
                else
                    f1 = decoded_Freq(i, 1);
                    f2 = decoded_Freq(i, 2);
                end
                output(1, i) = getDigit(f1, f2);
            end
            
            app.displayTextDec.BackgroundColor = [0.94 0.5 0.5];
            app.displayTextDec.Text = ['>> ', output];
            
            bufferValue = cell2mat(graphLabelsArray(1));
            app.PlotSelectDropDownSint.Value = bufferValue;
            
            app.DigitDropDown.Enable = 'off';
            app.PlotSelectDropDownSint.Value = 'Amplitud de señal completa vs Tiempo (s)';
            plotFunction(app, 'signal: amplitude vs time', app.t_signal_dec, app.Y_dec);
                
        end

        % Button pushed function: btn_num
        function btn_numButtonPushed(app, event)
            app.digitButtonPushed('#');
        end

        % Button pushed function: btn_runFilteredSignalDec
        function btn_runFilteredSignalDecButtonPushed(app, event)
            soundsc(app.Y_filtered, app.Fs);
        end

        % Button pushed function: btn_runLastSignalDec
        function btn_runLastSignalDecButtonPushed(app, event)
            soundsc(app.Y_dec, app.Fs);
        end

        % Button pushed function: btn_showStartTimeDec
        function btn_showStartTimeDecButtonPushed(app, event)
            y = app.Y_dec;
            t = app.t_signal_dec;
            i = 5000;
            bound = 0.01;
            
            while y(i) < bound
                i = i + 1;
            end
            
            app.consoleText.FontColor = [0, 0, 0];
            app.consoleText.Text = ['El instante en el que se da inicio a la señal es: ' num2str(t(i)) 's'];
            
        end

        % Button pushed function: btn_sintRun
        function btn_sintRunButtonPushed(app, event)
            if app.N_digits == 7
                
                % Enable and prepare items...!
                app.runLastSignalSint.Enable = 'on';
                app.PlotSelectDropDownSint.Enable = 'on';
                app.PlotSelectDropDownSint.Items = {
                    'Amplitud de señal completa vs Tiempo (s)'
                    'Magnitud de señal completa (dB)  vs Frecuencia (Hz)'
                    'Fase de señal completa (rad) vs Frecuencia (Hz)'
                };
                % ............................!
                
                i = 1;
                digits = char(app.displayTextSint.Text);
                app.Y_sint = app.delayArray;
                
                while i <= 7
                    digit = digits(i);
                    [f1, f2] = getFrequencies(digit);
                    [y, time] = DTMF(f1, f2, app.Fs, app.TD);
                    app.Y_sint = [app.Y_sint, y, app.delayArray];
                    i = i + 1;
                end
                
                app.consoleText.FontColor = [0.00, 0.00, 0.00];
                if app.saveCheckBox.Value == 1
                    fileName = 'tone.wav';
                    audiowrite(fileName, app.Y_sint, app.Fs);
                    app.consoleText.Text = '>> El archivo de tonos se ha generado y guardado con exito, pulse el boton "Reproducir ultimo" para escucharlo...!';
                else
                    app.consoleText.Text = '>> El archivo de tonos se ha generado con exito, pulse el boton "Reproducir ultimo" para escucharlo...!';
                end
                
                % Fast Fourier Transformation...!
                Y_fft = fft(app.Y_sint);
                Y_fft_cent = fftshift(Y_fft);
                app.Y_sint_mag = abs(Y_fft_cent);
                app.Y_sint_phase = unwrap(angle(Y_fft_cent));
                
                % Plot...!
                plotFunction(app, 'signal: amplitude vs time', app.t_signal_sint, app.Y_sint);
                app.PlotSelectDropDownSint.Value = 'Amplitud de señal completa vs Tiempo (s)';
                
            else
                app.consoleText.Text = '>> Debe ingresar 7 digitos para generar el archivo de tonos, por favor ingrese los digitos que hagan falta...!';
                app.consoleText.FontColor = [1.00, 0.00, 0.00];
            end
            
        end

        % Button pushed function: runLastSignalSint
        function runLastSignalSintButtonPushed(app, event)
            soundsc(app.Y_sint, app.Fs);
        end

        % Value changed function: saveCheckBox
        function saveCheckBoxValueChanged(app, event)
            value = app.saveCheckBox.Value;
            app.consoleText.FontColor = [0.00, 0.00, 0.00];
            if value == 1
                app.consoleText.Text = '>> El tono generado se guardará en el directorio raiz del proyecto con el nombre "tone.wav", el archivo se sobreescribirá con cada nuevo conjunto de tonos generado...!';
            else
                app.consoleText.Text = '>>';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SintetizadorTab = uitab(app.TabGroup);
            app.SintetizadorTab.Title = 'Sintetizador';
            app.brn_1 = uibutton(app.SintetizadorTab, 'push');
            app.brn_1.ButtonPushedFcn = createCallbackFcn(app, @brn_1_Pushed, true);
            app.brn_1.Text = '1';
            app.btn_2 = uibutton(app.SintetizadorTab, 'push');
            app.btn_2.ButtonPushedFcn = createCallbackFcn(app, @btn_2ButtonPushed, true);
            app.btn_2.Text = '2';
            app.btn_3 = uibutton(app.SintetizadorTab, 'push');
            app.btn_3.ButtonPushedFcn = createCallbackFcn(app, @btn_3ButtonPushed, true);
            app.btn_3.Text = '3';
            app.btn_4 = uibutton(app.SintetizadorTab, 'push');
            app.btn_4.ButtonPushedFcn = createCallbackFcn(app, @btn_4ButtonPushed, true);
            app.btn_4.Text = '4';
            app.btn_5 = uibutton(app.SintetizadorTab, 'push');
            app.btn_5.ButtonPushedFcn = createCallbackFcn(app, @btn_5ButtonPushed, true);
            app.btn_5.Text = '5';
            app.btn_6 = uibutton(app.SintetizadorTab, 'push');
            app.btn_6.ButtonPushedFcn = createCallbackFcn(app, @btn_6ButtonPushed, true);
            app.btn_6.Text = '6';
            app.btn_7 = uibutton(app.SintetizadorTab, 'push');
            app.btn_7.ButtonPushedFcn = createCallbackFcn(app, @btn_7ButtonPushed, true);
            app.btn_7.Text = '7';
            app.btn_8 = uibutton(app.SintetizadorTab, 'push');
            app.btn_8.ButtonPushedFcn = createCallbackFcn(app, @btn_8ButtonPushed, true);
            app.btn_8.Text = '8';
            app.btn_9 = uibutton(app.SintetizadorTab, 'push');
            app.btn_9.ButtonPushedFcn = createCallbackFcn(app, @btn_9ButtonPushed, true);
            app.btn_9.Text = '9';
            app.btn_ast = uibutton(app.SintetizadorTab, 'push');
            app.btn_ast.ButtonPushedFcn = createCallbackFcn(app, @btn_astButtonPushed, true);
            app.btn_ast.Text = '*';
            app.btn_0 = uibutton(app.SintetizadorTab, 'push');
            app.btn_0.ButtonPushedFcn = createCallbackFcn(app, @btn_0ButtonPushed, true);
            app.btn_0.Text = '0';
            app.btn_num = uibutton(app.SintetizadorTab, 'push');
            app.btn_num.ButtonPushedFcn = createCallbackFcn(app, @btn_numButtonPushed, true);
            app.btn_num.Text = '#';
            app.displaySint = uipanel(app.SintetizadorTab);
            app.displayTextSint = uilabel(app.displaySint);
            app.displayTextSint.Text = '';
            app.f1f2Label = uilabel(app.SintetizadorTab);
            app.f1f2Label.Text = 'f1 \ f2';
            app.Hz1336Hz1477HzLabel = uilabel(app.SintetizadorTab);
            app.Hz1336Hz1477HzLabel.Text = '1209 Hz    1336 Hz    1477 Hz';
            app.HzLabel = uilabel(app.SintetizadorTab);
            app.HzLabel.Text = '697 Hz';
            app.HzLabel_2 = uilabel(app.SintetizadorTab);
            app.HzLabel_2.Text = '770 Hz';
            app.HzLabel_3 = uilabel(app.SintetizadorTab);
            app.HzLabel_3.Text = '852 Hz';
            app.HzLabel_4 = uilabel(app.SintetizadorTab);
            app.HzLabel_4.Text = '941 Hz';
            app.btn_clear = uibutton(app.SintetizadorTab, 'push');
            app.btn_clear.ButtonPushedFcn = createCallbackFcn(app, @btn_clearButtonPushed, true);
            app.btn_clear.Enable = 'off';
            app.btn_clear.Text = 'Limpiar';
            app.btn_sintRun = uibutton(app.SintetizadorTab, 'push');
            app.btn_sintRun.ButtonPushedFcn = createCallbackFcn(app, @btn_sintRunButtonPushed, true);
            app.btn_sintRun.Text = 'Generar tonos';
            app.saveCheckBox = uicheckbox(app.SintetizadorTab);
            app.saveCheckBox.ValueChangedFcn = createCallbackFcn(app, @saveCheckBoxValueChanged, true);
            app.saveCheckBox.Text = 'Guardar la señal de audio generada...!';
            app.runLastSignalSint = uibutton(app.SintetizadorTab, 'push');
            app.runLastSignalSint.ButtonPushedFcn = createCallbackFcn(app, @runLastSignalSintButtonPushed, true);
            app.runLastSignalSint.Enable = 'off';
            app.runLastSignalSint.Text = 'Reproducir ultimo';
            app.DecodificadorTab = uitab(app.TabGroup);
            app.DecodificadorTab.Title = 'Decodificador';
            app.btn_decRun = uibutton(app.DecodificadorTab, 'push');
            app.btn_decRun.ButtonPushedFcn = createCallbackFcn(app, @btn_decRunButtonPushed, true);
            app.btn_decRun.Text = 'Iniciar Grabación';
            app.displayDec = uipanel(app.DecodificadorTab);
            app.displayDec.Title = 'Numeros decodificados';
            app.displayTextDec = uilabel(app.displayDec);
            app.displayTextDec.Text = '>> 9999999';
            app.btn_runLastSignalDec = uibutton(app.DecodificadorTab, 'push');
            app.btn_runLastSignalDec.ButtonPushedFcn = createCallbackFcn(app, @btn_runLastSignalDecButtonPushed, true);
            app.btn_runLastSignalDec.Text = 'Reproducir señal grabada';
            app.btn_showStartTimeDec = uibutton(app.DecodificadorTab, 'push');
            app.btn_showStartTimeDec.ButtonPushedFcn = createCallbackFcn(app, @btn_showStartTimeDecButtonPushed, true);
            app.btn_showStartTimeDec.Text = 'Mostrar instante inicial';
            app.btn_runFilteredSignalDec = uibutton(app.DecodificadorTab, 'push');
            app.btn_runFilteredSignalDec.ButtonPushedFcn = createCallbackFcn(app, @btn_runFilteredSignalDecButtonPushed, true);
            app.btn_runFilteredSignalDec.Text = 'Reproducir señal filtrada';
            app.panelDeGraficos = uipanel(app.UIFigure);
            app.Plane = uiaxes(app.panelDeGraficos);
            title(app.Plane, ' ')
            xlabel(app.Plane, ' ')
            app.Plane.XLim = [0 1];
            app.Plane.YLim = [0 1];
            app.Plane.CLim = [0 100];
            app.Plane.LineStyleOrder = {'- '};
            app.Plane.XTick = [];
            app.Plane.YTick = [];
            app.GraficoLabel = uilabel(app.panelDeGraficos);
            app.GraficoLabel.Text = 'Grafico:';
            app.PlotSelectDropDownSint = uidropdown(app.panelDeGraficos);
            app.PlotSelectDropDownSint.Items = {};
            app.PlotSelectDropDownSint.ValueChangedFcn = createCallbackFcn(app, @PlotSelectDropDownSintValueChanged, true);
            app.PlotSelectDropDownSint.Value = {};
            app.DigitoDropDownLabel = uilabel(app.panelDeGraficos);
            app.DigitoDropDownLabel.Text = 'Digito:';
            app.DigitDropDown = uidropdown(app.panelDeGraficos);
            app.DigitDropDown.Items = {'1°', '2°', '3°', '4°', '5°', '6°', '7°'};
            app.DigitDropDown.ValueChangedFcn = createCallbackFcn(app, @DigitDropDownValueChanged, true);
            app.DigitDropDown.Value = '1°';
            app.consoleText = uilabel(app.UIFigure);
            app.consoleText.Text = '>>';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end